classdef CFD_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RightPanel                      matlab.ui.container.Panel
        DefineGeometryPanel             matlab.ui.container.Panel
        GeoInterpDD                     matlab.ui.control.DropDown
        GeometryInterpretationMethodOrderLabel  matlab.ui.control.Label
        ShapeDropDown                   matlab.ui.control.DropDown
        ShapeDropDownLabel              matlab.ui.control.Label
        SwitchType                      matlab.ui.control.Switch
        SwitchTypeLabel                 matlab.ui.control.Label
        CoordinatesofLeadingEdgeY       matlab.ui.control.NumericEditField
        CoordinatesofLeadingEdgeX       matlab.ui.control.NumericEditField
        PositionofLeadingEdgexyLabel    matlab.ui.control.Label
        NACA4DigitCodeEditField         matlab.ui.control.EditField
        NACA4DigitCodeEditFieldLabel    matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        LengthmLabel                    matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        NumberofPointsEditField         matlab.ui.control.NumericEditField
        NumberofPointsEditFieldLabel    matlab.ui.control.Label
        DefineMeshPanel                 matlab.ui.container.Panel
        YSpacingEditField               matlab.ui.control.NumericEditField
        XSpacingEditField               matlab.ui.control.NumericEditField
        XYSpacingEditFieldLabel         matlab.ui.control.Label
        YDomainEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XDomainEditField                matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        GridSizecellsLabel              matlab.ui.control.Label
        DomainSizeLabel                 matlab.ui.control.Label
        YGridEditField                  matlab.ui.control.NumericEditField
        XGridEditField                  matlab.ui.control.NumericEditField
        IterationNumberField            matlab.ui.control.NumericEditField
        IterationLabel                  matlab.ui.control.Label
        SimulationTimeField             matlab.ui.control.NumericEditField
        SimulationRuntimeLabel          matlab.ui.control.Label
        TerminateSimulationButton       matlab.ui.control.Button
        PauseSimulationButton           matlab.ui.control.StateButton
        NewSimulationButton             matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        FlowVisualiser                  matlab.ui.control.UIAxes
        PostProcessingTab               matlab.ui.container.Tab
        ForceCoefficientsPanel          matlab.ui.container.Panel
        PlotClButton                    matlab.ui.control.Button
        PlotCdButton                    matlab.ui.control.Button
        C_LEditField                    matlab.ui.control.NumericEditField
        C_LEditFieldLabel               matlab.ui.control.Label
        C_DEditField                    matlab.ui.control.NumericEditField
        C_DEditFieldLabel               matlab.ui.control.Label
        StreamlinesPanel                matlab.ui.container.Panel
        SlinesStepsize                  matlab.ui.control.NumericEditField
        StreamwiseStepLabel             matlab.ui.control.Label
        SlinesDispVar                   matlab.ui.control.DropDown
        DisplayVarDropDownLabel_2       matlab.ui.control.Label
        PlotSlinesButton                matlab.ui.control.Button
        SlineYDelta                     matlab.ui.control.NumericEditField
        SlineXDelta                     matlab.ui.control.NumericEditField
        SlineYStart                     matlab.ui.control.NumericEditField
        SlineXStart                     matlab.ui.control.NumericEditField
        StartxPointyLabel               matlab.ui.control.Label
        SlinesYRes                      matlab.ui.control.NumericEditField
        YEditFieldLabel_2               matlab.ui.control.Label
        ResolutiondeltaLabel            matlab.ui.control.Label
        SlinesXRes                      matlab.ui.control.NumericEditField
        XEditFieldLabel_2               matlab.ui.control.Label
        InterpolationPanel              matlab.ui.container.Panel
        InterpolatedGridSizeXEditField  matlab.ui.control.NumericEditField
        InterpolatedGridSizeXYEditFieldLabel  matlab.ui.control.Label
        InterpolatedGridSizeYEditField  matlab.ui.control.NumericEditField
        VariabletoDisplayDropDown       matlab.ui.control.DropDown
        VariabletoDisplayDropDownLabel  matlab.ui.control.Label
        InterpolateButton               matlab.ui.control.Button
        InterpolatedFieldVisualiser     matlab.ui.control.UIAxes
        ResidualsTab                    matlab.ui.container.Tab
        ResidualsPlot                   matlab.ui.control.UIAxes
        SimulationTerminatedLabel       matlab.ui.control.Label
        LeftPanel                       matlab.ui.container.Panel
        ApplyWallBoundaryPanel          matlab.ui.container.Panel
        ApplytoWallLabel                matlab.ui.control.Label
        DefaultOutflowInletLOutletRLabel  matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        BoundaryDropDown                matlab.ui.control.DropDown
        VelocitynormalEditField         matlab.ui.control.NumericEditField
        VelocitynormalEditFieldLabel    matlab.ui.control.Label
        BoundaryDropDownLabel           matlab.ui.control.Label
        TopBCButton                     matlab.ui.control.Button
        RightBCButton                   matlab.ui.control.Button
        BottomBCButton                  matlab.ui.control.Button
        LeftBCButton                    matlab.ui.control.Button
        DisplayOptionsPanel             matlab.ui.container.Panel
        ExportButton                    matlab.ui.control.Button
        IterationsLabel                 matlab.ui.control.Label
        UpdateFreqEditField             matlab.ui.control.NumericEditField
        UpdateFreqEditFieldLabel        matlab.ui.control.Label
        UpdateDisplayButton             matlab.ui.control.Button
        OverlayGeometryCheckBox         matlab.ui.control.CheckBox
        DisplayVarDropDown              matlab.ui.control.DropDown
        DisplayVarDropDownLabel         matlab.ui.control.Label
        SolverParametersPanel           matlab.ui.container.Panel
        AutosaveFrequencyField          matlab.ui.control.NumericEditField
        SaveFreqIterEditFieldLabel      matlab.ui.control.Label
        LoadFileButton                  matlab.ui.control.Button
        MachNoEditField                 matlab.ui.control.NumericEditField
        MachNoEditFieldLabel            matlab.ui.control.Label
        ReynoldsNumberEditField         matlab.ui.control.NumericEditField
        ApproxReLabel                   matlab.ui.control.Label
        InletVelocityEditField          matlab.ui.control.NumericEditField
        InitialXVelmsLabel              matlab.ui.control.Label
        CurrentdtEditField              matlab.ui.control.NumericEditField
        CurrentdtEditFieldLabel         matlab.ui.control.Label
        CFLEditField                    matlab.ui.control.NumericEditField
        CFLLabel                        matlab.ui.control.Label
        EndTimesEditField               matlab.ui.control.NumericEditField
        EndTimesEditFieldLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        TerminateSimProperty % property to tell main code loop to stop if terminate app button pressed
        PlottingVariables
        simData % stores simulation data for access while sim is paused or after termination
        boundaryTypes = ["outflow", 'pressure outlet',"outflow",'velocity inlet'] % 4x1 string array containing boundary type data, all default to outflow, order is [T; R; B; L] (i.e. "clockwise from 12")
        boundaryVelocities = [0; 0; 0; 68.6]; % 4x1 double array containing boundary velocity data, all default to 0, order is [T; R; B; L] (i.e. "clockwise from 12") - note: this only applies when type is velocity inlet!
        plotCD
        CDhist
        plotCL
        loadFromFile = false
    end
    
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NewSimulationButton
        function NewSimulationButtonPushed(app, event)
            if isempty(app.NACA4DigitCodeEditField.Value)
                uialert(app.UIFigure, "You must select a NACA aerofoil code.", "Input Required!")
                return
            end
            
            % add required folders to path
            addpath(genpath('.'))

            % --- activate/disable required app components ----
            app.NewSimulationButton.Enable = "off";
            app.NewSimulationButton.Text = "Initialising...";
            pause(0.1) % allow time for updating GUI

            app.FlowVisualiser.Visible = "on";
            app.FlowVisualiser.Toolbar.Visible = "on";
            app.SimulationTerminatedLabel.Visible = "off";

            app.ResidualsPlot.Visible = "on";
            app.ResidualsPlot.Toolbar.Visible = "on";

            app.TerminateSimProperty = false;
            
            app.DisplayVarDropDown.Enable = "on"; % needs some sim data to display before it can be used

            % temporarily disabled, update button will instead be enabled
            % once sim data is updated in the main loop
            % app.UpdateDisplayButton.Enable = "on"; % can be activated once sim data is available

            app.ApplyWallBoundaryPanel.Enable = "off"; % don't allow changing of bcs mid simulation

            app.ExportButton.Enable = "on";

            app.SlinesDispVar.Enable = "on";

            app.plotCD = true;
            app.plotCL = false;

            try
                main_loop(app)
            catch me
                uialert(app.UIFigure, getReport(me), "Error!","Interpreter","html")
            end

            % cleanup after operation
            app.NewSimulationButton.Enable = "on";
            app.NewSimulationButton.Text = "New Simulation";
            app.TerminateSimulationButton.Enable = "off";
            app.PauseSimulationButton.Enable = "off";
        end

        % Button pushed function: TerminateSimulationButton
        function TerminateSimulationButtonPushed(app, event)
            app.TerminateSimProperty = true;
            app.TerminateSimulationButton.Enable = "off";
            app.SimulationTerminatedLabel.Visible = "on";
            
            app.PauseSimulationButton.Value = false;
            app.PauseSimulationButton.Text = 'Pause Simulation';
            app.PauseSimulationButton.FontWeight = "normal";
            app.PauseSimulationButton.Enable = "off";

            app.NewSimulationButton.Text = "New Simulation";
            app.NewSimulationButton.Enable = "on"; 

            app.ApplyWallBoundaryPanel.Enable = "on";

            app.UpdateDisplayButton.Enable = "off";
           
        end

        % Value changed function: PauseSimulationButton
        function PauseSimulationButtonValueChanged(app, event)
            if app.PauseSimulationButton.Value % true when simulation paused
                app.PauseSimulationButton.Text = 'Resume Simulation';
                app.PauseSimulationButton.FontWeight = "bold";
                app.NewSimulationButton.Text = "Simulation Paused...";
            else
                app.PauseSimulationButton.Text = 'Pause Simulation';
                app.PauseSimulationButton.FontWeight = "normal";
                app.NewSimulationButton.Text = "Simulation Running...";
            end
        end

        % Button pushed function: InterpolateButton
        function InterpolateButtonPushed(app, event)
            app.InterpolatedFieldVisualiser.Visible = "on";
            switch app.VariabletoDisplayDropDown.Value
                case "Density"
                    dataToInterpolate = app.simData.density;
                case "X Velocity"
                    dataToInterpolate = app.simData.xVelocity;
                case "Y Velocity"
                    dataToInterpolate = app.simData.yVelocity;
                case "Pressure"
                    dataToInterpolate = app.simData.energy;
            end

            % Problem / grid setup - TODO this is repeated in GPT.m, could
            % try to avoid duplication?
            Lx = app.XDomainEditField.Value; Ly = app.YDomainEditField.Value;                                     % domain size
            Nx = app.InterpolatedGridSizeXEditField.Value; Ny = app.InterpolatedGridSizeYEditField.Value;         % grid cells in x/y
            dx = Lx / Nx; dy = Ly / Ny;
            x = linspace(dx/2, Lx-dx/2, Nx);
            y = linspace(dy/2, Ly-dy/2, Ny);
            [interpolatedGridX, interpolatedGridY] = meshgrid(x, y);
            
            interpolatedField = interp2(app.simData.meshGridXY(:,:,1), app.simData.meshGridXY(:,:,2), dataToInterpolate, interpolatedGridX, interpolatedGridY);
            
            imagesc(app.InterpolatedFieldVisualiser, [x(1) Lx], [y(1) Ly], interpolatedField)
            set(app.InterpolatedFieldVisualiser,'YDir','normal');
            title(app.InterpolatedFieldVisualiser, sprintf('Interpolated %s at t = %.4f', app.VariabletoDisplayDropDown.Value, app.SimulationTimeField.Value));
            colorbar(app.InterpolatedFieldVisualiser);
            drawnow;

        end

        % Value changed function: DisplayVarDropDown
        function DisplayVarDropDownValueChanged(app, event)
            varToDisplay = app.DisplayVarDropDown.Value;
            flow_visualiser(app, varToDisplay)
        end

        % Button pushed function: BottomBCButton, LeftBCButton, 
        % ...and 2 other components
        function setWallBc(app, event)
            switch event.Source.Text
                case 'T'
                    app.boundaryTypes(1) = app.BoundaryDropDown.Value;
                    app.boundaryVelocities(1) = app.VelocitynormalEditField.Value;
                case 'R'
                    app.boundaryTypes(2) = app.BoundaryDropDown.Value;
                    app.boundaryVelocities(2) = app.VelocitynormalEditField.Value;
                case 'B'
                    app.boundaryTypes(3) = app.BoundaryDropDown.Value;
                    app.boundaryVelocities(3) = app.VelocitynormalEditField.Value;
                case 'L'
                    app.boundaryTypes(4) = app.BoundaryDropDown.Value;
                    app.boundaryVelocities(4) = app.VelocitynormalEditField.Value;
            end
        end

        % Value changed function: BoundaryDropDown
        function BoundaryDropDownValueChanged(app, event)
            if app.BoundaryDropDown.Value == "velocity inlet"
                app.msLabel.Visible = "on";
                app.VelocitynormalEditField.Visible = "on";
                app.VelocitynormalEditFieldLabel.Visible = "on";
            else
                app.msLabel.Visible = "off";
                app.VelocitynormalEditField.Visible = "off";
                app.VelocitynormalEditFieldLabel.Visible = "off";
            end
        end

        % Button pushed function: UpdateDisplayButton
        function UpdateDisplayButtonPushed(app, event)
            varToDisplay = app.DisplayVarDropDown.Value;
            flow_visualiser(app, varToDisplay);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportResults(app)
        end

        % Button pushed function: PlotSlinesButton
        function PlotSlinesButtonPushed(app, event)
            % initialise
            app.InterpolatedFieldVisualiser.Visible = "on";
            hold(app.InterpolatedFieldVisualiser,"off");
            flow_visualiser(app, app.SlinesDispVar.Value, app.InterpolatedFieldVisualiser);

            % data manipulation
            Starts = [app.SlineXStart.Value, app.SlineYStart.Value];
            Deltas = [app.SlineXDelta.Value, app.SlineYDelta.Value];
            Resols = [app.SlinesXRes.Value, app.SlinesYRes.Value];
            finalXVal = Starts(1)+Deltas(1)*(Resols(1)-1);
            finalYVal = Starts(2)+Deltas(2)*(Resols(2)-1);
            Xstream = Starts(1):Deltas(1):finalXVal;
            Ystream = Starts(2):Deltas(2):finalYVal;

            % variable creation
            X = app.simData.meshGridXY(:,:,1);
            Y = app.simData.meshGridXY(:,:,2);
            U = app.simData.xVelocity;
            V = app.simData.yVelocity;
            [startX, startY] = meshgrid(Xstream,Ystream);
            stepsize = app.SlinesStepsize.Value;
            
            % plot
            verts = stream2(X, Y, U, V, startX, startY, stepsize);
            hold(app.InterpolatedFieldVisualiser,"on");
            streamline(app.InterpolatedFieldVisualiser,verts);
            hold(app.InterpolatedFieldVisualiser,"off");
        end

        % Value changed function: XDomainEditField, XGridEditField, 
        % ...and 2 other components
        function XYDomainEditFieldValueChanged(app, event)
            xLength = app.XDomainEditField.Value;
            yLength = app.YDomainEditField.Value;

            xCells = app.XGridEditField.Value;
            yCells = app.YGridEditField.Value;

            dx = xLength/xCells;
            dy = yLength/yCells;
            
            app.XSpacingEditField.Value = dx;
            app.YSpacingEditField.Value = dy;
            
        end

        % Value changed function: SwitchType
        function SwitchTypeValueChanged(app, event)
            value = app.SwitchType.Value;
            if value == "Aerofoil"
                app.ShapeDropDown.Enable = "off";
                app.AngleEditFieldLabel.Text = "Angle of Attack";
                app.LengthEditFieldLabel.Text = "Chord Length";
                app.NACA4DigitCodeEditField.Enable = "on";
                app.PositionofLeadingEdgexyLabel.Text = "Grid Position of Leading Edge (x%,y%)";
            else % Shape
                app.ShapeDropDown.Enable = "on";
                app.AngleEditFieldLabel.Text = "Orientation";
                app.LengthEditFieldLabel.Text = "Radius/Width";
                app.NACA4DigitCodeEditField.Enable = "off";
                app.PositionofLeadingEdgexyLabel.Text = "Grid Position of Center (x%,y%)";
            end
        end

        % Button pushed function: PlotCdButton
        function PlotCdButtonPushed(app, event)
            app.InterpolatedFieldVisualiser.Visible = "on";
            app.plotCD = true;
            app.plotCL = false;
            title(app.InterpolatedFieldVisualiser, "C_D vs Iteration")
            xlabel(app.InterpolatedFieldVisualiser, "Iteration")
            ylabel(app.InterpolatedFieldVisualiser, "C_D")
            plot(app.InterpolatedFieldVisualiser, app.simData.coefficients(:,1))
        end

        % Button pushed function: PlotClButton
        function PlotClButtonPushed(app, event)
            app.InterpolatedFieldVisualiser.Visible = "on";
            app.plotCD = false;
            app.plotCL = true;
            title(app.InterpolatedFieldVisualiser, "C_L vs Iteration")
            xlabel(app.InterpolatedFieldVisualiser, "Iteration")
            ylabel(app.InterpolatedFieldVisualiser, "C_L")
            plot(app.InterpolatedFieldVisualiser, app.simData.coefficients(:,2))
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            if app.loadFromFile == true
                app.LoadFileButton.Text = "Load File";
                app.LoadFileButton.Tooltip = "No file loaded" ;
                app.loadFromFile = false;
            else
                [file, filePath] = uigetfile;
                fullFilePath = [filePath file];
                if file == 0
                    uialert(app.UIFigure, "No file found", "Error!","Interpreter","html")
                    figure(app.UIFigure)
                    return
                end
                loadedSimData = load(fullFilePath);
                app.simData = loadedSimData.simdataVariable;
                if ~isa(app.simData, "simDataStore")
                    uialert(app.UIFigure, "Selected file is not of simDataStore class", "Error!","Interpreter","html")
                    figure(app.UIFigure)
                    return
                else
                    uialert(app.UIFigure, ["Successfully loaded file:<br>" fullFilePath "<br>Boundary and freestream conditions will <b>not</b> be carried over from previous sim!"], "Warning","Icon","warning","Interpreter","html")
                end
                app.LoadFileButton.Tooltip = ["Loaded File = " fullFilePath];
                app.loadFromFile = true;
                app.LoadFileButton.Text = "Unload File";
                figure(app.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.SolverParametersPanel = uipanel(app.LeftPanel);
            app.SolverParametersPanel.Title = 'Solver Parameters';
            app.EndTimesEditFieldLabel = uilabel(app.SolverParametersPanel);
            app.EndTimesEditFieldLabel.Text = 'End Time (s)';
            app.EndTimesEditField = uieditfield(app.SolverParametersPanel, 'numeric');
            app.EndTimesEditField.Limits = [0 Inf];
            app.EndTimesEditField.Value = 1;
            app.CFLLabel = uilabel(app.SolverParametersPanel);
            app.CFLLabel.Text = 'CFL';
            app.CFLEditField = uieditfield(app.SolverParametersPanel, 'numeric');
            app.CFLEditField.Limits = [0 1];
            app.CFLEditField.Value = 0.3;
            app.CurrentdtEditFieldLabel = uilabel(app.SolverParametersPanel);
            app.CurrentdtEditFieldLabel.Text = 'Current dt';
            app.CurrentdtEditField = uieditfield(app.SolverParametersPanel, 'numeric');
            app.CurrentdtEditField.Limits = [0 1];
            app.CurrentdtEditField.Editable = 'off';
            app.InitialXVelmsLabel = uilabel(app.SolverParametersPanel);
            app.InitialXVelmsLabel.Text = 'Initial X Velocity (m/s)';
            app.InletVelocityEditField = uieditfield(app.SolverParametersPanel, 'numeric');
            app.InletVelocityEditField.Tooltip = {'Used to set initial condition for domain as well as defining the Mach no. and Approx Reynolds numbers.'};
            app.InletVelocityEditField.Value = 68.6;
            app.ApproxReLabel = uilabel(app.SolverParametersPanel);
            app.ApproxReLabel.Text = 'Approx. Re';
            app.ReynoldsNumberEditField = uieditfield(app.SolverParametersPanel, 'numeric');
            app.ReynoldsNumberEditField.ValueDisplayFormat = '%11.2g';
            app.ReynoldsNumberEditField.Editable = 'off';
            app.MachNoEditFieldLabel = uilabel(app.SolverParametersPanel);
            app.MachNoEditFieldLabel.Text = 'Mach No.';
            app.MachNoEditField = uieditfield(app.SolverParametersPanel, 'numeric');
            app.MachNoEditField.Editable = 'off';
            app.LoadFileButton = uibutton(app.SolverParametersPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.SaveFreqIterEditFieldLabel = uilabel(app.SolverParametersPanel);
            app.SaveFreqIterEditFieldLabel.Text = 'Save Freq (Iter.)';
            app.AutosaveFrequencyField = uieditfield(app.SolverParametersPanel, 'numeric');
            app.AutosaveFrequencyField.Value = 5000;
            app.DisplayOptionsPanel = uipanel(app.LeftPanel);
            app.DisplayOptionsPanel.Title = 'Display Options';
            app.DisplayVarDropDownLabel = uilabel(app.DisplayOptionsPanel);
            app.DisplayVarDropDownLabel.Text = 'Display Variable';
            app.DisplayVarDropDown = uidropdown(app.DisplayOptionsPanel);
            app.DisplayVarDropDown.Items = {'X Velocity', 'Y Velocity', 'Velocity_Magnitude', 'Mach', 'Density', 'Pressure', 'Temperature'};
            app.DisplayVarDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayVarDropDownValueChanged, true);
            app.DisplayVarDropDown.Enable = 'off';
            app.DisplayVarDropDown.Value = 'X Velocity';
            app.OverlayGeometryCheckBox = uicheckbox(app.DisplayOptionsPanel);
            app.OverlayGeometryCheckBox.Text = 'Overlay Geometry';
            app.OverlayGeometryCheckBox.Value = true;
            app.UpdateDisplayButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.UpdateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplayButtonPushed, true);
            app.UpdateDisplayButton.Enable = 'off';
            app.UpdateDisplayButton.Text = 'Update Display';
            app.UpdateFreqEditFieldLabel = uilabel(app.DisplayOptionsPanel);
            app.UpdateFreqEditFieldLabel.Text = 'Update Freq.';
            app.UpdateFreqEditField = uieditfield(app.DisplayOptionsPanel, 'numeric');
            app.UpdateFreqEditField.Limits = [0 Inf];
            app.UpdateFreqEditField.Value = 100;
            app.IterationsLabel = uilabel(app.DisplayOptionsPanel);
            app.IterationsLabel.Text = 'Iterations';
            app.ExportButton = uibutton(app.DisplayOptionsPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.ApplyWallBoundaryPanel = uipanel(app.LeftPanel);
            app.ApplyWallBoundaryPanel.Title = 'Apply Wall Boundary:';
            app.LeftBCButton = uibutton(app.ApplyWallBoundaryPanel, 'push');
            app.LeftBCButton.ButtonPushedFcn = createCallbackFcn(app, @setWallBc, true);
            app.LeftBCButton.Text = 'L';
            app.BottomBCButton = uibutton(app.ApplyWallBoundaryPanel, 'push');
            app.BottomBCButton.ButtonPushedFcn = createCallbackFcn(app, @setWallBc, true);
            app.BottomBCButton.Text = 'B';
            app.RightBCButton = uibutton(app.ApplyWallBoundaryPanel, 'push');
            app.RightBCButton.ButtonPushedFcn = createCallbackFcn(app, @setWallBc, true);
            app.RightBCButton.Text = 'R';
            app.TopBCButton = uibutton(app.ApplyWallBoundaryPanel, 'push');
            app.TopBCButton.ButtonPushedFcn = createCallbackFcn(app, @setWallBc, true);
            app.TopBCButton.Text = 'T';
            app.BoundaryDropDownLabel = uilabel(app.ApplyWallBoundaryPanel);
            app.BoundaryDropDownLabel.Text = 'Boundary';
            app.VelocitynormalEditFieldLabel = uilabel(app.ApplyWallBoundaryPanel);
            app.VelocitynormalEditFieldLabel.Visible = 'off';
            app.VelocitynormalEditFieldLabel.Text = 'Velocity (normal)';
            app.VelocitynormalEditField = uieditfield(app.ApplyWallBoundaryPanel, 'numeric');
            app.VelocitynormalEditField.Limits = [0 Inf];
            app.VelocitynormalEditField.Visible = 'off';
            app.VelocitynormalEditField.Value = 68.6;
            app.BoundaryDropDown = uidropdown(app.ApplyWallBoundaryPanel);
            app.BoundaryDropDown.Items = {'outflow', 'periodic', 'wall', 'velocity inlet', 'pressure outlet'};
            app.BoundaryDropDown.ValueChangedFcn = createCallbackFcn(app, @BoundaryDropDownValueChanged, true);
            app.BoundaryDropDown.Tooltip = {'Outflow - Neumann (copy)'; 'Velocity Inlet - Dirichlet (enforce u,v,rho)'; 'Pressure Outlet - Dirichlet (enforce p,rho)'};
            app.BoundaryDropDown.Value = 'velocity inlet';
            app.msLabel = uilabel(app.ApplyWallBoundaryPanel);
            app.msLabel.Visible = 'off';
            app.msLabel.Text = 'm/s';
            app.DefaultOutflowInletLOutletRLabel = uilabel(app.ApplyWallBoundaryPanel);
            app.DefaultOutflowInletLOutletRLabel.Text = 'Default: Outflow + Inlet(L)/Outlet(R)';
            app.ApplytoWallLabel = uilabel(app.ApplyWallBoundaryPanel);
            app.ApplytoWallLabel.Text = 'Apply to Wall';
            app.CenterPanel = uipanel(app.UIFigure);
            app.SimulationTerminatedLabel = uilabel(app.CenterPanel);
            app.SimulationTerminatedLabel.Visible = 'off';
            app.SimulationTerminatedLabel.Text = 'Simulation Terminated';
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.FlowVisualiser = uiaxes(app.SimulationTab);
            title(app.FlowVisualiser, 'Flow')
            xlabel(app.FlowVisualiser, 'X')
            ylabel(app.FlowVisualiser, 'Y')
            zlabel(app.FlowVisualiser, 'Z')
            app.FlowVisualiser.Visible = 'off';
            app.Image = uiimage(app.SimulationTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icons', 'XYAxis.png');
            app.PostProcessingTab = uitab(app.TabGroup);
            app.PostProcessingTab.Title = 'Post-Processing';
            app.InterpolatedFieldVisualiser = uiaxes(app.PostProcessingTab);
            title(app.InterpolatedFieldVisualiser, 'Title')
            xlabel(app.InterpolatedFieldVisualiser, 'X')
            ylabel(app.InterpolatedFieldVisualiser, 'Y')
            zlabel(app.InterpolatedFieldVisualiser, 'Z')
            app.InterpolatedFieldVisualiser.Visible = 'off';
            app.InterpolationPanel = uipanel(app.PostProcessingTab);
            app.InterpolationPanel.Title = 'Interpolation';
            app.InterpolateButton = uibutton(app.InterpolationPanel, 'push');
            app.InterpolateButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolateButtonPushed, true);
            app.InterpolateButton.Text = 'Interpolate';
            app.VariabletoDisplayDropDownLabel = uilabel(app.InterpolationPanel);
            app.VariabletoDisplayDropDownLabel.Text = 'Variable to Display';
            app.VariabletoDisplayDropDown = uidropdown(app.InterpolationPanel);
            app.VariabletoDisplayDropDown.Items = {'Density', 'X Velocity', 'Y Velocity', 'Energy'};
            app.VariabletoDisplayDropDown.Value = 'Density';
            app.InterpolatedGridSizeYEditField = uieditfield(app.InterpolationPanel, 'numeric');
            app.InterpolatedGridSizeYEditField.Limits = [0 Inf];
            app.InterpolatedGridSizeYEditField.Value = 500;
            app.InterpolatedGridSizeXYEditFieldLabel = uilabel(app.InterpolationPanel);
            app.InterpolatedGridSizeXYEditFieldLabel.Text = 'Interpolated Grid Size (X, Y)';
            app.InterpolatedGridSizeXEditField = uieditfield(app.InterpolationPanel, 'numeric');
            app.InterpolatedGridSizeXEditField.Limits = [0 Inf];
            app.InterpolatedGridSizeXEditField.Value = 500;
            app.StreamlinesPanel = uipanel(app.PostProcessingTab);
            app.StreamlinesPanel.Title = 'Streamlines';
            app.XEditFieldLabel_2 = uilabel(app.StreamlinesPanel);
            app.XEditFieldLabel_2.Text = 'X';
            app.SlinesXRes = uieditfield(app.StreamlinesPanel, 'numeric');
            app.SlinesXRes.Limits = [1 Inf];
            app.SlinesXRes.Value = 1;
            app.ResolutiondeltaLabel = uilabel(app.StreamlinesPanel);
            app.ResolutiondeltaLabel.Text = 'Resolution, delta:';
            app.YEditFieldLabel_2 = uilabel(app.StreamlinesPanel);
            app.YEditFieldLabel_2.Text = 'Y';
            app.SlinesYRes = uieditfield(app.StreamlinesPanel, 'numeric');
            app.SlinesYRes.Limits = [1 Inf];
            app.SlinesYRes.Value = 10;
            app.StartxPointyLabel = uilabel(app.StreamlinesPanel);
            app.StartxPointyLabel.Text = 'Start  (x) Point  (y)';
            app.SlineXStart = uieditfield(app.StreamlinesPanel, 'numeric');
            app.SlineYStart = uieditfield(app.StreamlinesPanel, 'numeric');
            app.SlineYStart.Value = 0.2;
            app.SlineXDelta = uieditfield(app.StreamlinesPanel, 'numeric');
            app.SlineXDelta.Value = 1;
            app.SlineYDelta = uieditfield(app.StreamlinesPanel, 'numeric');
            app.SlineYDelta.Value = 0.2;
            app.PlotSlinesButton = uibutton(app.StreamlinesPanel, 'push');
            app.PlotSlinesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSlinesButtonPushed, true);
            app.PlotSlinesButton.Text = 'Plot Streamlines';
            app.DisplayVarDropDownLabel_2 = uilabel(app.StreamlinesPanel);
            app.DisplayVarDropDownLabel_2.Text = 'Display Variable';
            app.SlinesDispVar = uidropdown(app.StreamlinesPanel);
            app.SlinesDispVar.Items = {'X Velocity', 'Y Velocity', 'Velocity_Magnitude', 'Mach', 'Density', 'Pressure', 'Temperature'};
            app.SlinesDispVar.Enable = 'off';
            app.SlinesDispVar.Value = 'X Velocity';
            app.StreamwiseStepLabel = uilabel(app.StreamlinesPanel);
            app.StreamwiseStepLabel.Text = 'Streamwise Step size (m) ';
            app.SlinesStepsize = uieditfield(app.StreamlinesPanel, 'numeric');
            app.SlinesStepsize.Limits = [1e-10 Inf];
            app.SlinesStepsize.Value = 0.4;
            app.ForceCoefficientsPanel = uipanel(app.PostProcessingTab);
            app.ForceCoefficientsPanel.Title = 'Force Coefficients';
            app.C_DEditFieldLabel = uilabel(app.ForceCoefficientsPanel);
            app.C_DEditFieldLabel.Interpreter = 'tex';
            app.C_DEditFieldLabel.Text = 'C_D';
            app.C_DEditField = uieditfield(app.ForceCoefficientsPanel, 'numeric');
            app.C_DEditField.Editable = 'off';
            app.C_LEditFieldLabel = uilabel(app.ForceCoefficientsPanel);
            app.C_LEditFieldLabel.Interpreter = 'tex';
            app.C_LEditFieldLabel.Text = 'C_L';
            app.C_LEditField = uieditfield(app.ForceCoefficientsPanel, 'numeric');
            app.C_LEditField.Editable = 'off';
            app.PlotCdButton = uibutton(app.ForceCoefficientsPanel, 'push');
            app.PlotCdButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCdButtonPushed, true);
            app.PlotCdButton.Text = 'Plot Cd';
            app.PlotClButton = uibutton(app.ForceCoefficientsPanel, 'push');
            app.PlotClButton.ButtonPushedFcn = createCallbackFcn(app, @PlotClButtonPushed, true);
            app.PlotClButton.Text = 'Plot Cl';
            app.ResidualsTab = uitab(app.TabGroup);
            app.ResidualsTab.Title = 'Residuals';
            app.ResidualsPlot = uiaxes(app.ResidualsTab);
            title(app.ResidualsPlot, 'Residuals')
            xlabel(app.ResidualsPlot, 'X')
            ylabel(app.ResidualsPlot, 'Y')
            zlabel(app.ResidualsPlot, 'Z')
            app.ResidualsPlot.Visible = 'off';
            app.RightPanel = uipanel(app.UIFigure);
            app.NewSimulationButton = uibutton(app.RightPanel, 'push');
            app.NewSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @NewSimulationButtonPushed, true);
            app.NewSimulationButton.Text = 'New Simulation';
            app.PauseSimulationButton = uibutton(app.RightPanel, 'state');
            app.PauseSimulationButton.ValueChangedFcn = createCallbackFcn(app, @PauseSimulationButtonValueChanged, true);
            app.PauseSimulationButton.Enable = 'off';
            app.PauseSimulationButton.Text = 'Pause Simulation';
            app.TerminateSimulationButton = uibutton(app.RightPanel, 'push');
            app.TerminateSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateSimulationButtonPushed, true);
            app.TerminateSimulationButton.Enable = 'off';
            app.TerminateSimulationButton.Text = 'Terminate Simulation';
            app.SimulationRuntimeLabel = uilabel(app.RightPanel);
            app.SimulationRuntimeLabel.Text = 'Simulation Time';
            app.SimulationTimeField = uieditfield(app.RightPanel, 'numeric');
            app.SimulationTimeField.Limits = [0 Inf];
            app.SimulationTimeField.Editable = 'off';
            app.IterationLabel = uilabel(app.RightPanel);
            app.IterationLabel.Text = 'Iteration';
            app.IterationNumberField = uieditfield(app.RightPanel, 'numeric');
            app.IterationNumberField.Limits = [0 Inf];
            app.IterationNumberField.Editable = 'off';
            app.DefineMeshPanel = uipanel(app.RightPanel);
            app.DefineMeshPanel.Title = 'Define Mesh';
            app.XGridEditField = uieditfield(app.DefineMeshPanel, 'numeric');
            app.XGridEditField.Limits = [0 Inf];
            app.XGridEditField.RoundFractionalValues = 'on';
            app.XGridEditField.ValueChangedFcn = createCallbackFcn(app, @XYDomainEditFieldValueChanged, true);
            app.XGridEditField.Value = 480;
            app.YGridEditField = uieditfield(app.DefineMeshPanel, 'numeric');
            app.YGridEditField.Limits = [0 Inf];
            app.YGridEditField.RoundFractionalValues = 'on';
            app.YGridEditField.ValueChangedFcn = createCallbackFcn(app, @XYDomainEditFieldValueChanged, true);
            app.YGridEditField.Value = 320;
            app.DomainSizeLabel = uilabel(app.DefineMeshPanel);
            app.DomainSizeLabel.Text = 'Domain Size (m)';
            app.GridSizecellsLabel = uilabel(app.DefineMeshPanel);
            app.GridSizecellsLabel.Text = 'Grid Size (cells)';
            app.XEditFieldLabel = uilabel(app.DefineMeshPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XDomainEditField = uieditfield(app.DefineMeshPanel, 'numeric');
            app.XDomainEditField.Limits = [0 Inf];
            app.XDomainEditField.RoundFractionalValues = 'on';
            app.XDomainEditField.ValueChangedFcn = createCallbackFcn(app, @XYDomainEditFieldValueChanged, true);
            app.XDomainEditField.Value = 15;
            app.YEditFieldLabel = uilabel(app.DefineMeshPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YDomainEditField = uieditfield(app.DefineMeshPanel, 'numeric');
            app.YDomainEditField.Limits = [0 Inf];
            app.YDomainEditField.RoundFractionalValues = 'on';
            app.YDomainEditField.ValueChangedFcn = createCallbackFcn(app, @XYDomainEditFieldValueChanged, true);
            app.YDomainEditField.Value = 10;
            app.XYSpacingEditFieldLabel = uilabel(app.DefineMeshPanel);
            app.XYSpacingEditFieldLabel.Text = 'X, Y Spacing';
            app.XSpacingEditField = uieditfield(app.DefineMeshPanel, 'numeric');
            app.XSpacingEditField.ValueDisplayFormat = '%11.3g';
            app.XSpacingEditField.Editable = 'off';
            app.XSpacingEditField.Value = 0.03125;
            app.YSpacingEditField = uieditfield(app.DefineMeshPanel, 'numeric');
            app.YSpacingEditField.ValueDisplayFormat = '%11.3g';
            app.YSpacingEditField.Editable = 'off';
            app.YSpacingEditField.Value = 0.03125;
            app.DefineGeometryPanel = uipanel(app.RightPanel);
            app.DefineGeometryPanel.Title = 'Define Geometry';
            app.NumberofPointsEditFieldLabel = uilabel(app.DefineGeometryPanel);
            app.NumberofPointsEditFieldLabel.Text = 'Number of Points';
            app.NumberofPointsEditField = uieditfield(app.DefineGeometryPanel, 'numeric');
            app.NumberofPointsEditField.Limits = [0 Inf];
            app.NumberofPointsEditField.RoundFractionalValues = 'on';
            app.NumberofPointsEditField.Tooltip = {'It is recommended for accuracy that the number of points on the geometry roughly matches the grid spacing. (i.e. for a circle of diameter 1m and spacing 0.01m, use 100*pi (~314) points)'};
            app.NumberofPointsEditField.Value = 132;
            app.degLabel = uilabel(app.DefineGeometryPanel);
            app.degLabel.Text = '(°)';
            app.LengthmLabel = uilabel(app.DefineGeometryPanel);
            app.LengthmLabel.Text = '(m)';
            app.LengthEditFieldLabel = uilabel(app.DefineGeometryPanel);
            app.LengthEditFieldLabel.Text = 'Chord Length';
            app.LengthEditField = uieditfield(app.DefineGeometryPanel, 'numeric');
            app.LengthEditField.Limits = [0 Inf];
            app.LengthEditField.Value = 0.5;
            app.AngleEditFieldLabel = uilabel(app.DefineGeometryPanel);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.DefineGeometryPanel, 'numeric');
            app.AngleEditField.Limits = [-180 180];
            app.NACA4DigitCodeEditFieldLabel = uilabel(app.DefineGeometryPanel);
            app.NACA4DigitCodeEditFieldLabel.Text = 'NACA 4 Digit Code';
            app.NACA4DigitCodeEditField = uieditfield(app.DefineGeometryPanel, 'text');
            app.NACA4DigitCodeEditField.CharacterLimits = [4 4];
            app.NACA4DigitCodeEditField.Placeholder = 'MPXX';
            app.NACA4DigitCodeEditField.Value = '0012';
            app.PositionofLeadingEdgexyLabel = uilabel(app.DefineGeometryPanel);
            app.PositionofLeadingEdgexyLabel.Text = 'Grid Position of Leading Edge (x%,y%)';
            app.CoordinatesofLeadingEdgeX = uieditfield(app.DefineGeometryPanel, 'numeric');
            app.CoordinatesofLeadingEdgeX.Limits = [0 100];
            app.CoordinatesofLeadingEdgeX.Value = 33;
            app.CoordinatesofLeadingEdgeY = uieditfield(app.DefineGeometryPanel, 'numeric');
            app.CoordinatesofLeadingEdgeY.Limits = [0 100];
            app.CoordinatesofLeadingEdgeY.Value = 50;
            app.SwitchTypeLabel = uilabel(app.DefineGeometryPanel);
            app.SwitchTypeLabel.Text = 'Switch Type';
            app.SwitchType = uiswitch(app.DefineGeometryPanel, 'slider');
            app.SwitchType.Items = {'Aerofoil', 'Shape'};
            app.SwitchType.ValueChangedFcn = createCallbackFcn(app, @SwitchTypeValueChanged, true);
            app.SwitchType.Value = 'Aerofoil';
            app.ShapeDropDownLabel = uilabel(app.DefineGeometryPanel);
            app.ShapeDropDownLabel.Enable = 'off';
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.DefineGeometryPanel);
            app.ShapeDropDown.Items = {'Square', 'Cylinder', 'Triangle', 'Plate'};
            app.ShapeDropDown.Enable = 'off';
            app.ShapeDropDown.Value = 'Cylinder';
            app.GeometryInterpretationMethodOrderLabel = uilabel(app.DefineGeometryPanel);
            app.GeometryInterpretationMethodOrderLabel.Text = 'Geometry Interpretation Method (Order)';
            app.GeoInterpDD = uidropdown(app.DefineGeometryPanel);
            app.GeoInterpDD.Items = {'None (<1)', 'Volume Fraction Weighting (~1)', 'Velocity Interpolation (~2)'};
            app.GeoInterpDD.Value = 'None (<1)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CFD_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end